classdef MicroplateGR4 < matlab.apps.AppBase

   % Properties that correspond to app components
   properties (Access = public)
      MicroplateGR4UIFigure       matlab.ui.Figure
      FileMenu                    matlab.ui.container.Menu
      OpenCSVFileMenu             matlab.ui.container.Menu
      CloseFileMenu               matlab.ui.container.Menu
      ExitMenu                    matlab.ui.container.Menu
      PlateMenu                   matlab.ui.container.Menu
      ShowPlateMenu               matlab.ui.container.Menu
      ShowCurvesMenu              matlab.ui.container.Menu
      ShowModelMenu               matlab.ui.container.Menu
      ExportKineticsdataMenu      matlab.ui.container.Menu
      ExportODdataMenu            matlab.ui.container.Menu
      ExportLndataMenu            matlab.ui.container.Menu
      ModelMenu                   matlab.ui.container.Menu
      SelectModelMenu             matlab.ui.container.Menu
      FitmodelMenu                matlab.ui.container.Menu
      TimehLnODLabel              matlab.ui.control.Label
      ShowModelGraphCheckBox      matlab.ui.control.CheckBox
      ShowSpreadsheetCheckBox     matlab.ui.control.CheckBox
      SlopeGREditField            matlab.ui.control.NumericEditField
      SlopeGRLabel                matlab.ui.control.Label
      RMSEEditField               matlab.ui.control.NumericEditField
      RMSEEditFieldLabel          matlab.ui.control.Label
      SWRegressionLineLabel       matlab.ui.control.Label
      MidLnODEditField            matlab.ui.control.NumericEditField
      MidpointEditField           matlab.ui.control.NumericEditField
      MidpointEditFieldLabel      matlab.ui.control.Label
      TotalnumberofdatapointsEditField  matlab.ui.control.NumericEditField
      TotalnumberofdatapointsEditFieldLabel  matlab.ui.control.Label
      StdevEditField              matlab.ui.control.NumericEditField
      StdevEditFieldLabel         matlab.ui.control.Label
      ShowHistogramCheckBox       matlab.ui.control.CheckBox
      BlanksEditField             matlab.ui.control.EditField
      BlanksEditFieldLabel        matlab.ui.control.Label
      ProcessbyNEditField         matlab.ui.control.NumericEditField
      ProcessbyNEditFieldLabel    matlab.ui.control.Label
      BlankMeanEditField          matlab.ui.control.NumericEditField
      BlankMeanLabel              matlab.ui.control.Label
      SelectDataButton            matlab.ui.control.Button
      ClearWellButton             matlab.ui.control.Button
      DataFileNameEditField       matlab.ui.control.EditField
      DataFileNameEditFieldLabel  matlab.ui.control.Label
      SelectControlsButton        matlab.ui.control.Button
      DataFilePathEditField       matlab.ui.control.EditField
      DataFilePathEditFieldLabel  matlab.ui.control.Label
   end

   %MicroplateGR4
   
   
   properties (Access = private)
      Model          % ProcWells2 object
      currentPath    % Path to data folder
      fileName = ""  % Name of data file
      default_model  % int: Gompertz (1)  or Baranyi (2)
      namelist       %names of models        
      blankCellString  % blank data from selected wells
      Version = 4.0  %string with version number
   end
   
   methods (Access = private)
   
      %only called from FitmodelMenuSelected, so data present 
      function postOptRes(app,res_struct)
      
         init_params = res_struct.initParams;
         opt_params = res_struct.predParams;
         resmat = [init_params; opt_params];
         tab = array2table(resmat,'RowNames',{'Initial Params', ...
            'Optimized Params'},'VariableNames',{'MinLnOD', ...
            'GR','Lag','MaxOD'});
         tab = movevars(tab,"MinLnOD",'After',"Lag");
         modelname = app.namelist(app.default_model);
         namestr = modelname + " model";
         fig = uifigure('Name', namestr);
         uitable(fig, 'Data', tab, ...
            'Units', 'normalized', ...
            'Position', [0 0 1 1], ...
            'ColumnWidth', {'auto','auto','auto','auto'});
      end
   
      %Export .csv file with XYY data for optical density or natural log
      function ExportCurves(app, ODorLn)
         if app.fileName ~= "" 
            dataCellString = app.Model.getListString();
            if ~strcmp(dataCellString,"")
               processbyN = app.ProcessbyNEditField.Value;
               app.Model.setModel(app.default_model)
               curveData = app.Model.getCurveData(dataCellString, ...
                     app.blankCellString, processbyN);
               if ODorLn
                  mat = curveData.XYY;
               else
                  mat = curveData.LnXYY;
               end
               tab = array2table(mat);
               names = dataCellString;
               namevars = [{'Time'},names];
               allVars = 1:width(tab);
               tab2 = renamevars(tab,allVars,namevars);
               [file, path] = uiputfile('*.csv', ...
                  'Save growth curve',"spreadsheet_data.csv");
               if file ~= 0
                  fullname = fullfile(path,file);   
                  writetable(tab2,fullname); 
               end
            else
               msgbox("Please select wells to process from the graph", ...
                  "Error");
            end
         else
            msgbox("There are no data to process, please open a .csv file", ...
               "Error");
         end
      end
      
   end
   

   % Callbacks that handle component events
   methods (Access = private)

      % Code that executes after component creation
      function startupFcn(app)
         
         %initialize the cell string and set model to Gompertz
         app.blankCellString = "";
         app.default_model = 1;
         app.namelist = {"Gompertz", "Baranyi"};
      end

      % Menu selected function: OpenCSVFileMenu
      function OpenCSVFileMenuSelected(app, event)
          
         % Open the file dialog box to get the file name and path
         [filename, filepath] = uigetfile({'*.csv';'*.*'},...
            "Open .csv data file"); 
         % If the user did not cancel, read file and load MPmodel4
         if filename == 0
               return      
         else % user did not cancel, read the file, load data
            try
                file = fullfile(filepath, filename);  %assembles full path
                cd(filepath);
                plate_XYY = readtable(file);          
                [~,name,ext] = fileparts(file);
                displayName = strcat(name,ext);       %generate data name
                app.Model = ProcWells2(plate_XYY,displayName);
                app.currentPath = filepath;
                app.fileName = displayName;
                app.default_model = 1;
                app.Model.showPlate();
                app.TotalnumberofdatapointsEditField.Value = app.Model.TPs;
                app.DataFilePathEditField.Value = app.currentPath;
                app.DataFileNameEditField.Value = app.fileName;
            catch
                errordlg('please check the data file, format and path', ...
                   'File format error');
            end
         end
      end

      % Button pushed function: ClearWellButton
      function ClearWellButtonPushed(app, event)
         
         %unselect call wells in the plate graph
         if app.fileName ~= ""
            app.Model.clearWells();
         else
            msgbox("There are no data to process, please open a .csv file", ...
               "Message");
         end
      end

      % Button pushed function: SelectControlsButton
      function SelectControlsButtonPushed(app, event)
         
         %get cell string from plate graph and process model blank values
         if app.fileName ~= ""
            app.blankCellString = app.Model.getListString();
            if strcmp(app.blankCellString,"")
               app.BlankMeanEditField.Value = 0;
               app.BlanksEditField.Value = "";
               app.SlopeGREditField.Value = 0;
               app.MidpointEditField.Value = 0;
               app.MidLnODEditField.Value = 0;
               app.RMSEEditField.Value = 0;
               msgbox("Please select wells to process from the graph", ...
               "Error");
            else
               app.Model.clearWells(); 
               blankdata = app.Model.getBlankData(app.blankCellString);
               app.BlankMeanEditField.Value = blankdata.mean;
               app.StdevEditField.Value = blankdata.std;
               blanklabel = strjoin(blankdata.labelstr, ",");
               app.BlanksEditField.Value = blanklabel;
               if app.ShowHistogramCheckBox.Value == 1
                  app.Model.plotBlankData(blankdata);
               end
            end
         else
            msgbox("There are no data to process, please open a .csv file", ...
               "Error");
         end
      end

      % Button pushed function: SelectDataButton
      function SelectDataButtonPushed(app, event)
         
         %get cell string from plate graph and process model data
         if app.fileName ~= ""
            dataCellString = app.Model.getListString();
            if ~strcmp(dataCellString,"")
               processbyN = app.ProcessbyNEditField.Value;
               app.Model.setModel(app.default_model);
               curveData = app.Model.getCurveData(dataCellString, ...
                  app.blankCellString, processbyN);    
               [midtime, midval] = app.Model.getMidPoint(curveData);
               app.MidpointEditField.Value = midtime;
               app.MidLnODEditField.Value = midval;
               namestr = "Sliding window parameters";
               if app.ShowSpreadsheetCheckBox.Value
                  fig = uifigure('Name', namestr);
                  tab = curveData.ResultsTable;
                  uitable(fig, 'Data', tab, ...
                     'Units', 'normalized', ...
                     'Position', [0 0 1 1], ...
                     'ColumnWidth', {'fit','1x','1x','1x','1x', ...
                     '1x','1x','1x'});
               end
               if app.ShowModelGraphCheckBox.Value
                  app.ShowModelMenuSelected();
               end
            else
               msgbox("Please select wells to process from the graph", ...
               "Error");
            end
         else
            msgbox("There are no data to process, please open a .csv file", ...
               "Error");
         end
         %end
      end

      % Menu selected function: CloseFileMenu
      function CloseFileMenuSelected(app, event)
         
         %close plate data file and rezero all 
         if app.fileName ~= ""
            close all;
            app.default_model = 1;
            app.TotalnumberofdatapointsEditField.Value = 0;
            app.BlankMeanEditField.Value = 0;
            app.StdevEditField.Value = 0;
            app.BlanksEditField.Value = "";
            app.DataFileNameEditField.Value = "";
            app.DataFilePathEditField.Value = "";
            app.MidpointEditField.Value = 0;
            app.MidLnODEditField.Value = 0;
            app.SlopeGREditField.Value = 0;
            app.RMSEEditField.Value = 0;
            app.ProcessbyNEditField.Value = 10;
            app.fileName = "";
            blankmat = zeros(48,97);
            blankmat(:,1) = 1:48;
            EmptyPlate = table(blankmat);
            app.Model = ProcWells2(EmptyPlate,"");
         end   
      end

      % Menu selected function: ExitMenu
      function ExitMenuSelected(app, event)
         
         %close program
         close all;
         delete(app);
      end

      % Menu selected function: ShowPlateMenu
      function ShowPlateMenuSelected(app, event)
         
         %check for data and post interactive plate graph
         if app.fileName ~= ""
            app.Model.showPlate();
         else
            msgbox("There are no data to process, please open a .csv file", ...
               "Error");
         end
      end

      % Menu selected function: ShowCurvesMenu
      function ShowCurvesMenuSelected(app, event)
         
         %show uncorrected optical density data graph for selected wells
         if app.fileName ~= ""
            dataCellString = app.Model.getListString();
            if ~strcmp(dataCellString,"")
               app.Model.showCurves(dataCellString);
            else
               msgbox("Please select wells to process from the graph", ...
                  "Error");
            end
         else
            msgbox("There are no data to process, please open a .csv file", ...
               "Error");
         end
      end

      % Menu selected function: ShowModelMenu
      function ShowModelMenuSelected(app, event)
         
         %show the current default model with obs data and SW fit
         if app.fileName ~= ""
            dataCellString = app.Model.getListString();
            if ~strcmp(dataCellString,"")
               processbyN = app.ProcessbyNEditField.Value;
               app.Model.setModel(app.default_model);
               curveData = app.Model.showModel(dataCellString, ...
                  app.blankCellString,processbyN);
               [midtime, midval] = app.Model.getMidPoint(curveData);
               app.MidpointEditField.Value = midtime;
               app.MidLnODEditField.Value = midval;
               app.RMSEEditField.Value = curveData.grdata.RMSE;
               app.SlopeGREditField.Value = curveData.grdata.Slope;
            else
               msgbox("Please select wells to process from the graph", ...
                  "Error");
            end
         else
            msgbox("There are no data to process, please open a .csv file", ...
               "Error");
         end   
      end

      % Menu selected function: ExportKineticsdataMenu
      function ExportKineticsdataMenuSelected(app, event)
         
         %write SW data table to a file
         if app.fileName ~= "" 
            dataCellString = app.Model.getListString();
            if ~strcmp(dataCellString,"")
               processbyN = app.ProcessbyNEditField.Value;
               app.Model.setModel(app.default_model)
               curveData = app.Model.getCurveData(dataCellString, ...
                     app.blankCellString, processbyN);
               tab = curveData.ResultsTable; 
               [file, path] = uiputfile('*.csv', ...
                  'Save kinetics data file',"kinetics_data.csv");
               fullname = fullfile(path,file);   
               writetable(tab,fullname); 
            else
               msgbox("Please select wells to process from the graph", ...
                  "Error");
            end
         else
            msgbox("There are no data to process, please open a .csv file", ...
               "Error");
         end
      end

      % Menu selected function: SelectModelMenu
      function SelectModelMenuSelected(app, event)
         
         %OK - cancel box with model options
         [selectionIndex, ok] = listdlg('ListString', app.namelist, ...
                        'PromptString', 'Select an option:', ...
                        'SelectionMode', 'single', ...
                        'InitialValue', app.default_model, ...
                        'ListSize', [100 50]);
         if ok
            app.default_model = selectionIndex;
         end         
      end

      % Menu selected function: FitmodelMenu
      function FitmodelMenuSelected(app, event)
         
         %call fminsearch function to derive model parameters, post table 
         if app.fileName ~= "" 
            dataCellString = app.Model.getListString();
            if ~strcmp(dataCellString,"")
               dataCellString = app.Model.getListString(); 
               processbyN = app.ProcessbyNEditField.Value;
               app.Model.setModel(app.default_model)
               curveData = app.Model.getCurveData(dataCellString, ...
                     app.blankCellString, processbyN);
               LnXY = curveData.grdata.ObsLnXY;
               GR = curveData.grdata.ResTable.GR;
               minOD = curveData.grdata.ResTable.MIN;
               maxOD = curveData.grdata.ResTable.MAX;
               lag = curveData.grdata.ResTable.LAG;
               init_params = [minOD,GR,lag,maxOD];
               if app.default_model == 1
                  res_struct = app.Model.optimizeGompertz(LnXY,init_params);
               else
                  res_struct = app.Model.optimizeBaranyi(LnXY,init_params);
               end
               app.postOptRes(res_struct);
               title = strjoin(dataCellString,",");
               app.Model.plotfit(res_struct.obsData, res_struct.predData, ...
                  res_struct.predParams,curveData,title,res_struct.RSME);
            else
               msgbox("Please select wells to process from the graph", ...
                  "Error");
            end
         else
            msgbox("There are no data to process, please open a .csv file", ...
               "Error");
         end
      end

      % Menu selected function: ExportODdataMenu
      function ExportODdataMenuSelected(app, event)
         
         %1 = OD data
         app.ExportCurves(1);
      end

      % Menu selected function: ExportLndataMenu
      function ExportLndataMenuSelected(app, event)
         
         %0 = Ln data
         app.ExportCurves(0);
      end
   end

   % Component initialization
   methods (Access = private)

      % Create UIFigure and components
      function createComponents(app)
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         app.MicroplateGR4UIFigure = uifigure('Visible', 'off');
         app.MicroplateGR4UIFigure.Name = 'MicroplateGR4';
         app.FileMenu = uimenu(app.MicroplateGR4UIFigure);
         app.FileMenu.Text = 'File';
         app.OpenCSVFileMenu = uimenu(app.FileMenu);
         app.OpenCSVFileMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenCSVFileMenuSelected, true);
         app.OpenCSVFileMenu.Separator = 'on';
         app.OpenCSVFileMenu.Accelerator = 'o';
         app.OpenCSVFileMenu.Text = 'Open CSV File';
         app.CloseFileMenu = uimenu(app.FileMenu);
         app.CloseFileMenu.MenuSelectedFcn = createCallbackFcn(app, @CloseFileMenuSelected, true);
         app.CloseFileMenu.Accelerator = 'w';
         app.CloseFileMenu.Text = 'Close File';
         app.ExitMenu = uimenu(app.FileMenu);
         app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
         app.ExitMenu.Separator = 'on';
         app.ExitMenu.Accelerator = 'q';
         app.ExitMenu.Text = 'Exit';
         app.PlateMenu = uimenu(app.MicroplateGR4UIFigure);
         app.PlateMenu.Text = 'Plate';
         app.ShowPlateMenu = uimenu(app.PlateMenu);
         app.ShowPlateMenu.MenuSelectedFcn = createCallbackFcn(app, @ShowPlateMenuSelected, true);
         app.ShowPlateMenu.Accelerator = 'p';
         app.ShowPlateMenu.Text = 'Show Plate';
         app.ShowCurvesMenu = uimenu(app.PlateMenu);
         app.ShowCurvesMenu.MenuSelectedFcn = createCallbackFcn(app, @ShowCurvesMenuSelected, true);
         app.ShowCurvesMenu.Accelerator = 'c';
         app.ShowCurvesMenu.Text = 'Show Curves';
         app.ShowModelMenu = uimenu(app.PlateMenu);
         app.ShowModelMenu.MenuSelectedFcn = createCallbackFcn(app, @ShowModelMenuSelected, true);
         app.ShowModelMenu.Accelerator = 'm';
         app.ShowModelMenu.Text = 'Show Model';
         app.ExportKineticsdataMenu = uimenu(app.PlateMenu);
         app.ExportKineticsdataMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportKineticsdataMenuSelected, true);
         app.ExportKineticsdataMenu.Separator = 'on';
         app.ExportKineticsdataMenu.Accelerator = 'k';
         app.ExportKineticsdataMenu.Text = 'Export Kinetics data';
         app.ExportODdataMenu = uimenu(app.PlateMenu);
         app.ExportODdataMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportODdataMenuSelected, true);
         app.ExportODdataMenu.Accelerator = 'D';
         app.ExportODdataMenu.Text = 'Export OD data';
         app.ExportLndataMenu = uimenu(app.PlateMenu);
         app.ExportLndataMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportLndataMenuSelected, true);
         app.ExportLndataMenu.Accelerator = 'l';
         app.ExportLndataMenu.Text = 'Export Ln data';
         app.ModelMenu = uimenu(app.MicroplateGR4UIFigure);
         app.ModelMenu.Text = 'Model';
         app.SelectModelMenu = uimenu(app.ModelMenu);
         app.SelectModelMenu.MenuSelectedFcn = createCallbackFcn(app, @SelectModelMenuSelected, true);
         app.SelectModelMenu.Accelerator = 's';
         app.SelectModelMenu.Text = 'Select Model';
         app.FitmodelMenu = uimenu(app.ModelMenu);
         app.FitmodelMenu.MenuSelectedFcn = createCallbackFcn(app, @FitmodelMenuSelected, true);
         app.FitmodelMenu.Separator = 'on';
         app.FitmodelMenu.Accelerator = 'F';
         app.FitmodelMenu.Text = 'Fit model';
         app.DataFilePathEditFieldLabel = uilabel(app.MicroplateGR4UIFigure);
         app.DataFilePathEditFieldLabel.Text = 'Data File Path';
         app.DataFilePathEditField = uieditfield(app.MicroplateGR4UIFigure, 'text');
         app.DataFilePathEditField.Editable = 'off';
         app.SelectControlsButton = uibutton(app.MicroplateGR4UIFigure, 'push');
         app.SelectControlsButton.ButtonPushedFcn = createCallbackFcn(app, @SelectControlsButtonPushed, true);
         app.SelectControlsButton.Text = 'Select Controls';
         app.DataFileNameEditFieldLabel = uilabel(app.MicroplateGR4UIFigure);
         app.DataFileNameEditFieldLabel.Text = 'Data File Name';
         app.DataFileNameEditField = uieditfield(app.MicroplateGR4UIFigure, 'text');
         app.DataFileNameEditField.Editable = 'off';
         app.ClearWellButton = uibutton(app.MicroplateGR4UIFigure, 'push');
         app.ClearWellButton.ButtonPushedFcn = createCallbackFcn(app, @ClearWellButtonPushed, true);
         app.ClearWellButton.Text = 'Clear Wells';
         app.SelectDataButton = uibutton(app.MicroplateGR4UIFigure, 'push');
         app.SelectDataButton.ButtonPushedFcn = createCallbackFcn(app, @SelectDataButtonPushed, true);
         app.SelectDataButton.Text = 'Select Data';
         app.BlankMeanLabel = uilabel(app.MicroplateGR4UIFigure);
         app.BlankMeanLabel.Text = 'Blank Mean:';
         app.BlankMeanEditField = uieditfield(app.MicroplateGR4UIFigure, 'numeric');
         app.BlankMeanEditField.Editable = 'off';
         app.ProcessbyNEditFieldLabel = uilabel(app.MicroplateGR4UIFigure);
         app.ProcessbyNEditFieldLabel.Text = 'Process by N:';
         app.ProcessbyNEditField = uieditfield(app.MicroplateGR4UIFigure, 'numeric');
         app.ProcessbyNEditField.Limits = [3 30];
         app.ProcessbyNEditField.RoundFractionalValues = 'on';
         app.ProcessbyNEditField.Value = 10;
         app.BlanksEditFieldLabel = uilabel(app.MicroplateGR4UIFigure);
         app.BlanksEditFieldLabel.Text = 'Blanks:';
         app.BlanksEditField = uieditfield(app.MicroplateGR4UIFigure, 'text');
         app.BlanksEditField.Editable = 'off';
         app.ShowHistogramCheckBox = uicheckbox(app.MicroplateGR4UIFigure);
         app.ShowHistogramCheckBox.Text = 'Show Histogram';
         app.StdevEditFieldLabel = uilabel(app.MicroplateGR4UIFigure);
         app.StdevEditFieldLabel.Text = 'Stdev';
         app.StdevEditField = uieditfield(app.MicroplateGR4UIFigure, 'numeric');
         app.StdevEditField.ValueDisplayFormat = '%e';
         app.TotalnumberofdatapointsEditFieldLabel = uilabel(app.MicroplateGR4UIFigure);
         app.TotalnumberofdatapointsEditFieldLabel.Text = 'Total number of data points:';
         app.TotalnumberofdatapointsEditField = uieditfield(app.MicroplateGR4UIFigure, 'numeric');
         app.TotalnumberofdatapointsEditField.Editable = 'off';
         app.MidpointEditFieldLabel = uilabel(app.MicroplateGR4UIFigure);
         app.MidpointEditFieldLabel.Text = 'Midpoint';
         app.MidpointEditField = uieditfield(app.MicroplateGR4UIFigure, 'numeric');
         app.MidpointEditField.Editable = 'off';
         app.MidLnODEditField = uieditfield(app.MicroplateGR4UIFigure, 'numeric');
         app.MidLnODEditField.Editable = 'off';
         app.SWRegressionLineLabel = uilabel(app.MicroplateGR4UIFigure);
         app.SWRegressionLineLabel.Text = 'SW Regression Line:';
         app.RMSEEditFieldLabel = uilabel(app.MicroplateGR4UIFigure);
         app.RMSEEditFieldLabel.Text = 'RMSE';
         app.RMSEEditField = uieditfield(app.MicroplateGR4UIFigure, 'numeric');
         app.RMSEEditField.Editable = 'off';
         app.SlopeGRLabel = uilabel(app.MicroplateGR4UIFigure);
         app.SlopeGRLabel.Text = 'Slope (GR)';
         app.SlopeGREditField = uieditfield(app.MicroplateGR4UIFigure, 'numeric');
         app.SlopeGREditField.Editable = 'off';
         app.ShowSpreadsheetCheckBox = uicheckbox(app.MicroplateGR4UIFigure);
         app.ShowSpreadsheetCheckBox.Text = 'Show Spreadsheet';
         app.ShowSpreadsheetCheckBox.Value = true;
         app.ShowModelGraphCheckBox = uicheckbox(app.MicroplateGR4UIFigure);
         app.ShowModelGraphCheckBox.Text = 'Show Model Graph';
         app.TimehLnODLabel = uilabel(app.MicroplateGR4UIFigure);
         app.TimehLnODLabel.Text = ' Time (h)                  Ln OD';
         % Show the figure after all components are created
         app.MicroplateGR4UIFigure.Visible = 'on';
      end
   end

   % App creation and deletion
   methods (Access = public)

      % Construct app
      function app = MicroplateGR4

         runningApp = getRunningApp(app);

         % Check for running singleton app
         if isempty(runningApp)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MicroplateGR4UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)
         else

            % Focus the running singleton app
            figure(runningApp.MicroplateGR4UIFigure)

            app = runningApp;
         end

         if nargout == 0
            clear app
         end
      end

      % Code that executes before app deletion
      function delete(app)

         % Delete UIFigure when app is deleted
         delete(app.MicroplateGR4UIFigure)
      end
   end
end